classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterfaceSqueak                 matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TabSegmentation                 matlab.ui.container.Tab
        FilesSelectionPanel_2           matlab.ui.container.Panel
        SelectDirectoryButton           matlab.ui.control.StateButton
        ListBox                         matlab.ui.control.ListBox
        CurrentDirLabel                 matlab.ui.control.Label
        CurrentDirEditField             matlab.ui.control.EditField
        FilesDirectoryWAVLabel          matlab.ui.control.Label
        SegmentSelectedButton           matlab.ui.control.Button
        SegmentALLButton                matlab.ui.control.Button
        SegmentationOptionsPanel        matlab.ui.container.Panel
        Threshold1SliderLabel           matlab.ui.control.Label
        Threshold1Slider                matlab.ui.control.Slider
        Threshold2SliderLabel           matlab.ui.control.Label
        Threshold2Slider                matlab.ui.control.Slider
        PreProcessingoptionsPanel       matlab.ui.container.Panel
        CutoffFrequencykHZLabel         matlab.ui.control.Label
        CutoffFrequencykHZSlider        matlab.ui.control.Slider
        SamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyHzEditField    matlab.ui.control.NumericEditField
        PosProcessingBagofVisualWordsCheckBox  matlab.ui.control.CheckBox
        TabReviewSegmentation           matlab.ui.container.Tab
        spectrogramDisp                 matlab.ui.control.UIAxes
        AcceptButton                    matlab.ui.control.Button
        RejectButton                    matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        PreviousButton                  matlab.ui.control.Button
        FileSelectionPanel              matlab.ui.container.Panel
        xlsFilePathEditFieldLabel       matlab.ui.control.Label
        xlsFilePathEditField            matlab.ui.control.EditField
        ChooseFileButton                matlab.ui.control.Button
        SliderCorrection                matlab.ui.control.Slider
        numVoc                          matlab.ui.control.Label
        STARTButton                     matlab.ui.control.Button
        ENDButton                       matlab.ui.control.Button
        ClassificationTab               matlab.ui.container.Tab
        FilesSelectionPanel             matlab.ui.container.Panel
        SelectDirectoryButton_2         matlab.ui.control.StateButton
        ListBox_2                       matlab.ui.control.ListBox
        CurrentDirectoryEditFieldLabel  matlab.ui.control.Label
        CurrentDirectoryEditField       matlab.ui.control.EditField
        FilesDirectoryxlsLabel          matlab.ui.control.Label
        ClassifySelectedButton          matlab.ui.control.Button
        ClassifyALLButton               matlab.ui.control.Button
        ClassificationModelDropDownLabel  matlab.ui.control.Label
        ClassificationModelDropDown     matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        path_segm=addpath(genpath('.\Functions\Segmentation'))
        path_class=addpath(genpath('.\Functions\Classification'))
    end
    
    properties (Access = public)
        table_correction=[];
        num_correction=0;
        Fs_correction=0;
        path_file_correction='';
        idx_correction_reject=[];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SelectDirectoryButton
        function SelectDirectoryButtonValueChanged(app, event)
            value = app.SelectDirectoryButton.Value;
            
            if(value==1)
                %[file,path] = uigetfile(fullfile('D:\Dados Tese','.wav'));
                selpath = uigetdir;
                app.CurrentDirEditField.Value=selpath;
                list_wav = dir(fullfile(selpath,'*.wav'));
                app.ListBox.Items={list_wav.name};
            end
        end

        % Button pushed function: ChooseFileButton
        function ChooseFileButtonPushed(app, event)
            [file,path] = uigetfile('*.xls');
            filename=[path,'\',file];
            app.xlsFilePathEditField.Value=filename;
            app.path_file_correction.Value=filename;
            
            [~,~,RAW]=xlsread(filename);
            RAW=RAW(2:end,:);
            
            app.table_correction=RAW;
            app.num_correction=0;
            app.Fs_correction=500000;
            app.idx_correction_reject=[];
        end

        % Button pushed function: SegmentSelectedButton
        function SegmentSelectedButtonPushed(app, event)
            list_all=app.ListBox.Items;
            if(size(list_all,2)==0)
                %f = warndlg('No compatible files in the directory!','Warning');
                uialert(app.InterfaceSqueak,'No compatible files in the directory!','ERROR');
            else
                threshold1=app.Threshold1Slider.Value;
                threshold2=app.Threshold2Slider.Value;
                cut_freq=round(app.CutoffFrequencykHZSlider.Value*1000);
                Fs=app.SamplingFrequencyHzEditField.Value;
                pos_proc=app.PosProcessingBagofVisualWordsCheckBox.Value;
                
                list_selected=app.ListBox.Value;
                for i=1:length(list_selected)
                    spectral_entropy_segmentation(strcat(app.CurrentDirEditField.Value,'\'),list_all{i},cut_freq,threshold1,threshold2,pos_proc);                end
            end
        end

        % Button pushed function: SegmentALLButton
        function SegmentALLButtonPushed(app, event)
            list_all=app.ListBox.Items;
            if(size(list_all,2)==0)
                %f = warndlg('No compatible files in the directory!','Warning');
                uialert(app.InterfaceSqueak,'No compatible files in the directory!','ERROR');
            else
                threshold1=app.Threshold1Slider.Value;
                threshold2=app.Threshold2Slider.Value;
                cut_freq=round(app.CutoffFrequencykHZSlider.Value*1000);
                Fs=app.SamplingFrequencyHzEditField.Value;
                pos_proc=app.PosProcessingBagofVisualWordsCheckBox.Value;
                
                for i=1:length(list_all)
                    spectral_entropy_segmentation(strcat(app.CurrentDirEditField.Value,'\'),list_all{i},cut_freq,threshold1,threshold2,pos_proc);
                end
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            if(~strcmp(app.path_file_correction,''))
                
                app.num_correction=1;
                path_signal_correction=app.table_correction{1,20};
                name_signal_correction=app.table_correction{1,21};
                
                ini=ceil(app.table_correction{app.num_correction,2}*app.Fs_correction);
                fim=ceil(app.table_correction{app.num_correction,3}*app.Fs_correction);
                [y_voc,Fs]= audioread([path_signal_correction,'\',name_signal_correction],[ini fim]);
                
                axesSpec=app.spectrogramDisp;
                [S,F,T]=spectrogram(y_voc,256,128,1024,Fs,'yaxis','MinThreshold',-110);
                imagesc(axesSpec, T, F, log(1+abs(S)) ); %plot the log spectrum
                set(axesSpec,'YDir', 'normal');
                app.spectrogramDisp.XLim=[0,max(T)];
                app.spectrogramDisp.YLim=[0,max(F)];
                %app.spectrogramDisp.Title.String=[num2str(app.num_correction),' of ',num2str(size(app.table_correction),1)-1];
                app.numVoc.Text=['Vocalization ',num2str(app.num_correction),' of ',num2str(size(app.table_correction,1)-1)];
                
                app.SliderCorrection.Value=app.num_correction;
                app.SliderCorrection.Limits=[1,(size(app.table_correction,1)-1)];
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            
            if(~strcmp(app.path_file_correction,''))
                app.num_correction=app.num_correction+1;
                
                path_signal_correction=app.table_correction{1,20};
                name_signal_correction=app.table_correction{1,21};
                
                ini=ceil(app.table_correction{app.num_correction,2}*app.Fs_correction);
                fim=ceil(app.table_correction{app.num_correction,3}*app.Fs_correction);
                [y_voc,Fs]= audioread([path_signal_correction,'\',name_signal_correction],[ini fim]);
                
                axesSpec=app.spectrogramDisp;
                [S,F,T]=spectrogram(y_voc,256,128,1024,Fs,'yaxis','MinThreshold',-110);
                imagesc(axesSpec, T, F, log(1+abs(S))); %plot the log spectrum
                set(axesSpec,'YDir', 'normal');
                app.spectrogramDisp.XLim=[0,max(T)];
                app.spectrogramDisp.YLim=[0,max(F)];
                %app.spectrogramDisp.Title.String=[num2str(app.num_correction),' of ',num2str(size(app.table_correction),1)-1];
                app.numVoc.Text=['Vocalization ',num2str(app.num_correction),' of ',num2str(size(app.table_correction,1)-1)];
                app.SliderCorrection.Value=app.num_correction;
            end
            
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if(~strcmp(app.path_file_correction,''))
                
                if(app.num_correction-1>1)
                    app.num_correction=app.num_correction-1;
                else
                    app.num_correction=1;
                end
                
                path_signal_correction=app.table_correction{1,20};
                name_signal_correction=app.table_correction{1,21};
                
                ini=ceil(app.table_correction{app.num_correction,2}*app.Fs_correction);
                fim=ceil(app.table_correction{app.num_correction,3}*app.Fs_correction);
                [y_voc,Fs]= audioread([path_signal_correction,'\',name_signal_correction],[ini fim]);
                
                axesSpec=app.spectrogramDisp;
                [S,F,T]=spectrogram(y_voc,256,128,1024,Fs,'yaxis','MinThreshold',-110);
                imagesc(axesSpec, T, F, log(1+abs(S)) ); %plot the log spectrum
                set(axesSpec,'YDir', 'normal');
                app.spectrogramDisp.XLim=[0,max(T)];
                app.spectrogramDisp.YLim=[0,max(F)];
                %app.spectrogramDisp.Title.String=[num2str(app.num_correction),' of ',num2str(size(app.table_correction),1)-1];
                app.numVoc.Text=['Vocalization ',num2str(app.num_correction),' of ',num2str(size(app.table_correction,1)-1)];
                
                app.SliderCorrection.Value=app.num_correction;
            end
        end

        % Value changing function: SliderCorrection
        function SliderCorrectionValueChanging(app, event)
            if(~strcmp(app.path_file_correction,''))
                changingValue = event.Value;
                app.num_correction=round(changingValue);
                
                path_signal_correction=app.table_correction{1,20};
                name_signal_correction=app.table_correction{1,21};
                
                ini=ceil(app.table_correction{app.num_correction,2}*app.Fs_correction);
                fim=ceil(app.table_correction{app.num_correction,3}*app.Fs_correction);
                [y_voc,Fs]= audioread([path_signal_correction,'\',name_signal_correction],[ini fim]);
                
                axesSpec=app.spectrogramDisp;
                [S,F,T]=spectrogram(y_voc,256,128,1024,Fs,'yaxis','MinThreshold',-110);
                imagesc(axesSpec, T, F, log(1+abs(S)) ); %plot the log spectrum
                set(axesSpec,'YDir', 'normal');
                app.spectrogramDisp.XLim=[0,max(T)];
                app.spectrogramDisp.YLim=[0,max(F)];
                %app.spectrogramDisp.Title.String=[num2str(app.num_correction),' of ',num2str(size(app.table_correction),1)-1];
                app.numVoc.Text=['Vocalization ',num2str(app.num_correction),' of ',num2str(size(app.table_correction,1)-1)];
            end
        end

        % Value changed function: SamplingFrequencyHzEditField
        function SamplingFrequencyHzEditFieldValueChanged(app, event)
            value = app.SamplingFrequencyHzEditField.Value;
            app.CutoffFrequencykHZSlider.Limits=[0,round((app.SamplingFrequencyHzEditField.Value/2)/1000)];
        end

        % Button pushed function: RejectButton
        function RejectButtonPushed(app, event)
            if(~strcmp(app.path_file_correction,''))
                
                app.idx_correction_reject=[app.idx_correction_reject,app.num_correction];
                app.idx_correction_reject=unique(app.idx_correction_reject);
                app.num_correction=app.num_correction+1;
                
                path_signal_correction=app.table_correction{1,20};
                name_signal_correction=app.table_correction{1,21};
                
                ini=ceil(app.table_correction{app.num_correction,2}*app.Fs_correction);
                fim=ceil(app.table_correction{app.num_correction,3}*app.Fs_correction);
                [y_voc,Fs]= audioread([path_signal_correction,'\',name_signal_correction],[ini fim]);
                
                axesSpec=app.spectrogramDisp;
                [S,F,T]=spectrogram(y_voc,256,128,1024,Fs,'yaxis','MinThreshold',-110);
                imagesc(axesSpec, T, F, log(1+abs(S))); %plot the log spectrum
                set(axesSpec,'YDir', 'normal');
                app.spectrogramDisp.XLim=[0,max(T)];
                app.spectrogramDisp.YLim=[0,max(F)];
                %app.spectrogramDisp.Title.String=[num2str(app.num_correction),' of ',num2str(size(app.table_correction),1)-1];
                app.numVoc.Text=['Vocalization ',num2str(app.num_correction),' of ',num2str(size(app.table_correction,1)-1)];
                app.SliderCorrection.Value=app.num_correction;
            end
        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)
            if(~strcmp(app.path_file_correction,''))
                
                if(ismember(app.num_correction,app.idx_correction_reject))
                    app.idx_correction_reject(app.idx_correction_reject==app.num_correction) = [];
                end
                
                app.num_correction=app.num_correction+1;
                
                path_signal_correction=app.table_correction{1,20};
                name_signal_correction=app.table_correction{1,21};
                
                ini=ceil(app.table_correction{app.num_correction,2}*app.Fs_correction);
                fim=ceil(app.table_correction{app.num_correction,3}*app.Fs_correction);
                [y_voc,Fs]= audioread([path_signal_correction,'\',name_signal_correction],[ini fim]);
                
                axesSpec=app.spectrogramDisp;
                [S,F,T]=spectrogram(y_voc,256,128,1024,Fs,'yaxis','MinThreshold',-110);
                imagesc(axesSpec, T, F, log(1+abs(S))); %plot the log spectrum
                set(axesSpec,'YDir', 'normal');
                app.spectrogramDisp.XLim=[0,max(T)];
                app.spectrogramDisp.YLim=[0,max(F)];
                %app.spectrogramDisp.Title.String=[num2str(app.num_correction),' of ',num2str(size(app.table_correction),1)-1];
                app.numVoc.Text=['Vocalization ',num2str(app.num_correction),' of ',num2str(size(app.table_correction,1)-1)];
                app.SliderCorrection.Value=app.num_correction;
            end
        end

        % Button pushed function: ENDButton
        function ENDButtonPushed(app, event)
            if(~strcmp(app.path_file_correction,''))
                
                [~,~,RAW]=xlsread(app.path_file_correction.Value);
                configs=RAW(2,16:21);
                RAW([app.idx_correction_reject+1],:)=[];
                RAW(:,1)=num2cell(1:1:size(RAW,1));
                RAW(2,16:21)=configs;
                
                delete(app.path_file_correction.Value)
                xlswrite(app.path_file_correction.Value,RAW,'Folha1','A1');
                
                app.spectrogramDisp.cla;
                app.num_correction=0;
                app.table_correction=[];
                app.xlsFilePathEditField.Value='';
                app.path_file_correction='';
                app.idx_correction_reject=[];
            end
        end

        % Value changed function: SelectDirectoryButton_2
        function SelectDirectoryButton_2ValueChanged(app, event)
            value = app.SelectDirectoryButton_2.Value;
            if(value==1)
                %[file,path] = uigetfile(fullfile('D:\Dados Tese','.wav'));
                selpath = uigetdir;
                app.CurrentDirectoryEditField.Value=selpath;
                list_xls = dir(fullfile(selpath,'*.xls'));
                app.ListBox_2.Items={list_xls.name};
            end
            
        end

        % Button pushed function: ClassifySelectedButton
        function ClassifySelectedButtonPushed(app, event)
            list_all=app.ListBox_2.Items;
            if(size(list_all,2)==0)
                uialert(app.InterfaceSqueak,'No compatible files in the directory!','ERROR');
            else
                
                list_selected=app.ListBox_2.Value;
                files_dir=app.CurrentDirectoryEditField.Value;
                
                for i=1:length(list_selected)
                    filename=strcat(files_dir,'\',list_selected{i});
                    classify_xls(filename,'');
                end
            end
        end

        % Button pushed function: ClassifyALLButton
        function ClassifyALLButtonPushed(app, event)
            list_all=app.ListBox_2.Items;
            if(size(list_all,2)==0)
                uialert(app.InterfaceSqueak,'No compatible files in the directory!','ERROR');
            else
                files_dir=app.CurrentDirectoryEditField.Value;
                
                for i=1:length(list_selected)
                    filename=strcat(files_dir,'\',list_all{i});
                    classify_xls(filename,'');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InterfaceSqueak = uifigure('Visible', 'off');
            app.InterfaceSqueak.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.InterfaceSqueak);
            app.TabSegmentation = uitab(app.TabGroup);
            app.TabSegmentation.Title = 'Segmentation';
            app.FilesSelectionPanel_2 = uipanel(app.TabSegmentation);
            app.FilesSelectionPanel_2.Title = 'Files Selection';
            app.SelectDirectoryButton = uibutton(app.FilesSelectionPanel_2, 'state');
            app.SelectDirectoryButton.ValueChangedFcn = createCallbackFcn(app, @SelectDirectoryButtonValueChanged, true);
            app.SelectDirectoryButton.Text = 'Select Directory';
            app.ListBox = uilistbox(app.FilesSelectionPanel_2);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {};
            app.CurrentDirLabel = uilabel(app.FilesSelectionPanel_2);
            app.CurrentDirLabel.Text = 'Current Dir.';
            app.CurrentDirEditField = uieditfield(app.FilesSelectionPanel_2, 'text');
            app.FilesDirectoryWAVLabel = uilabel(app.FilesSelectionPanel_2);
            app.FilesDirectoryWAVLabel.Text = 'Files Directory (.WAV)';
            app.SegmentSelectedButton = uibutton(app.FilesSelectionPanel_2, 'push');
            app.SegmentSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentSelectedButtonPushed, true);
            app.SegmentSelectedButton.Text = 'Segment Selected';
            app.SegmentALLButton = uibutton(app.FilesSelectionPanel_2, 'push');
            app.SegmentALLButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentALLButtonPushed, true);
            app.SegmentALLButton.Text = 'Segment ALL';
            app.SegmentationOptionsPanel = uipanel(app.TabSegmentation);
            app.SegmentationOptionsPanel.Title = 'Segmentation Options';
            app.Threshold1SliderLabel = uilabel(app.SegmentationOptionsPanel);
            app.Threshold1SliderLabel.Text = 'Threshold 1';
            app.Threshold1Slider = uislider(app.SegmentationOptionsPanel);
            app.Threshold1Slider.Limits = [0 1];
            app.Threshold1Slider.Value = 0.97;
            app.Threshold2SliderLabel = uilabel(app.SegmentationOptionsPanel);
            app.Threshold2SliderLabel.Text = 'Threshold 2';
            app.Threshold2Slider = uislider(app.SegmentationOptionsPanel);
            app.Threshold2Slider.Limits = [0 1];
            app.Threshold2Slider.Value = 0.95;
            app.PreProcessingoptionsPanel = uipanel(app.TabSegmentation);
            app.PreProcessingoptionsPanel.Title = 'Pre-Processing options';
            app.CutoffFrequencykHZLabel = uilabel(app.PreProcessingoptionsPanel);
            app.CutoffFrequencykHZLabel.Text = {'Cut-off Frequency'; '(kHZ)'};
            app.CutoffFrequencykHZSlider = uislider(app.PreProcessingoptionsPanel);
            app.CutoffFrequencykHZSlider.Limits = [0 250];
            app.CutoffFrequencykHZSlider.Value = 30;
            app.SamplingFrequencyHzEditFieldLabel = uilabel(app.PreProcessingoptionsPanel);
            app.SamplingFrequencyHzEditFieldLabel.Text = 'Sampling Frequency (Hz)';
            app.SamplingFrequencyHzEditField = uieditfield(app.PreProcessingoptionsPanel, 'numeric');
            app.SamplingFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencyHzEditFieldValueChanged, true);
            app.SamplingFrequencyHzEditField.Value = 500000;
            app.PosProcessingBagofVisualWordsCheckBox = uicheckbox(app.PreProcessingoptionsPanel);
            app.PosProcessingBagofVisualWordsCheckBox.Text = 'Pos-Processing (Bag of Visual Words)';
            app.TabReviewSegmentation = uitab(app.TabGroup);
            app.TabReviewSegmentation.Title = 'Review Segmentation';
            app.spectrogramDisp = uiaxes(app.TabReviewSegmentation);
            xlabel(app.spectrogramDisp, 'Time')
            ylabel(app.spectrogramDisp, 'Frequency ')
            app.AcceptButton = uibutton(app.TabReviewSegmentation, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Text = 'Accept';
            app.RejectButton = uibutton(app.TabReviewSegmentation, 'push');
            app.RejectButton.ButtonPushedFcn = createCallbackFcn(app, @RejectButtonPushed, true);
            app.RejectButton.Text = 'Reject';
            app.NextButton = uibutton(app.TabReviewSegmentation, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.TabReviewSegmentation, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.FileSelectionPanel = uipanel(app.TabReviewSegmentation);
            app.FileSelectionPanel.Title = 'File Selection';
            app.xlsFilePathEditFieldLabel = uilabel(app.FileSelectionPanel);
            app.xlsFilePathEditFieldLabel.Text = '.xls File Path';
            app.xlsFilePathEditField = uieditfield(app.FileSelectionPanel, 'text');
            app.ChooseFileButton = uibutton(app.FileSelectionPanel, 'push');
            app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButtonPushed, true);
            app.ChooseFileButton.Text = 'Choose File';
            app.SliderCorrection = uislider(app.TabReviewSegmentation);
            app.SliderCorrection.ValueChangingFcn = createCallbackFcn(app, @SliderCorrectionValueChanging, true);
            app.numVoc = uilabel(app.TabReviewSegmentation);
            app.numVoc.Text = '';
            app.STARTButton = uibutton(app.TabReviewSegmentation, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.ENDButton = uibutton(app.TabReviewSegmentation, 'push');
            app.ENDButton.ButtonPushedFcn = createCallbackFcn(app, @ENDButtonPushed, true);
            app.ENDButton.Text = 'END';
            app.ClassificationTab = uitab(app.TabGroup);
            app.ClassificationTab.Title = 'Classification';
            app.FilesSelectionPanel = uipanel(app.ClassificationTab);
            app.FilesSelectionPanel.Title = 'Files Selection';
            app.SelectDirectoryButton_2 = uibutton(app.FilesSelectionPanel, 'state');
            app.SelectDirectoryButton_2.ValueChangedFcn = createCallbackFcn(app, @SelectDirectoryButton_2ValueChanged, true);
            app.SelectDirectoryButton_2.Text = 'Select Directory';
            app.ListBox_2 = uilistbox(app.FilesSelectionPanel);
            app.ListBox_2.Items = {};
            app.ListBox_2.Multiselect = 'on';
            app.ListBox_2.Value = {};
            app.CurrentDirectoryEditFieldLabel = uilabel(app.FilesSelectionPanel);
            app.CurrentDirectoryEditFieldLabel.Text = 'Current Directory';
            app.CurrentDirectoryEditField = uieditfield(app.FilesSelectionPanel, 'text');
            app.FilesDirectoryxlsLabel = uilabel(app.FilesSelectionPanel);
            app.FilesDirectoryxlsLabel.Text = 'Files Directory (.xls)';
            app.ClassifySelectedButton = uibutton(app.FilesSelectionPanel, 'push');
            app.ClassifySelectedButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifySelectedButtonPushed, true);
            app.ClassifySelectedButton.Text = 'Classify Selected';
            app.ClassifyALLButton = uibutton(app.FilesSelectionPanel, 'push');
            app.ClassifyALLButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyALLButtonPushed, true);
            app.ClassifyALLButton.Text = 'Classify ALL';
            app.ClassificationModelDropDownLabel = uilabel(app.FilesSelectionPanel);
            app.ClassificationModelDropDownLabel.Text = 'Classification Model';
            app.ClassificationModelDropDown = uidropdown(app.FilesSelectionPanel);
            app.ClassificationModelDropDown.Items = {'Tree Ensemble', 'SVM', 'Decision Tree', 'k-NN', 'Fisher-LDA'};
            app.ClassificationModelDropDown.Value = 'Tree Ensemble';
            % Show the figure after all components are created
            app.InterfaceSqueak.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterfaceSqueak)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterfaceSqueak)
        end
    end
end